classdef Copy_of_app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        OpenvertexLabel          matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        ClosedvertexLabel        matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        EvolvingsetLabel         matlab.ui.control.Label
        LegendLabel              matlab.ui.control.Label
        ParametersLabel          matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        iterationEditField       matlab.ui.control.NumericEditField
        iterationEditFieldLabel  matlab.ui.control.Label
        sizeEditField            matlab.ui.control.NumericEditField
        sizeEditFieldLabel       matlab.ui.control.Label
        muEditField              matlab.ui.control.NumericEditField
        muLabel                  matlab.ui.control.Label
        pEditField               matlab.ui.control.NumericEditField
        pEditFieldLabel          matlab.ui.control.Label
        StartButton              matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
writerObj = VideoWriter("newfile.avi");
writerObj.FrameRate = 30; % 设置帧率
open(writerObj);
axis(app.UIAxes, 'equal'); 
app.UIAxes.XColor = 'none';
app.UIAxes.YColor = 'none';
drawx = zeros(6,1);
drawy = zeros(6,1);
a = 3 ^ (1/2) ;
p0 = app.pEditField.Value;
l1 = [-1/2, 1/2, 1, 1/2,-1/2, -1];
l2 = [a/2, a/2, 0, -a/2, -a/2, 0];
size = app.sizeEditField.Value;
app.UIAxes.XLim = [0, size *2-5];
app.UIAxes.YLim = [0, size *2-5];
evolv_number = 1;
evolv_x(1) = floor(size/2);
evolv_y(1) = floor(size/2);
evolve = zeros(size,size);
for i = 1 : size
    for j = 1 : size
        if (mod(i, 2) == 1)
            center(i,j,1) = i * 3/2;
            center(i,j,2) = j * a;
        else
            center(i,j,1) = i * 3/2;
            center(i,j,2) = j * a + 1/2 * a;
        end

        for k = 1 : 6
            drawx(k) = center(i,j,1) + l1(k);
            drawy(k) = center(i,j,2) + l2(k);
        end
        
        rnumber = rand();
        if rnumber<p0
            if evolve(i,j) == 0
                patch(app.UIAxes, drawx,drawy,'red','FaceColor','white');
            else
                patch(app.UIAxes, drawx,drawy,'red','FaceColor','yellow');
            end
            map(i,j) = 1;
        else
            patch(app.UIAxes, drawx,drawy,'red','FaceColor','cyan');
            map(i,j) = 0;
        end
    end
end
x = floor(size / 2);
y = floor(size / 2);
map(x,y) = 1;
evolve(x,y) = 1;
iter_num = app.iterationEditField.Value;
for k = 1 : 6
            drawx(k) = center(x,y,1) + l1(k);
            drawy(k) = center(x,y,2) + l2(k);
end
patch(app.UIAxes,drawx,drawy,'red','FaceColor','blue');
transition_P = zeros(size,size,size,size);
refreshrate = app.muEditField.Value;
evenx = [-1,-1,0,0,1,1];
eveny = [0,1,-1,1,0,1];
oddx = [-1,-1,0,0,1,1];
oddy = [0,-1,-1,1,0,-1];
newx = 0; newy = 0;
for t = 1 : iter_num
    app.iterationEditField.Value = t;
    randomInteger = randi([1, 6]);
    for i = 1 : size
        for j = 1 : size
            refreshrand = rand();
            if (i == x && j == y) 
                continue;
            end
            if refreshrand < refreshrate
                for k = 1 : 6
                    drawx(k) = center(i,j,1) + l1(k);
                    drawy(k) = center(i,j,2) + l2(k);
                end
                rnumber = rand();
                if rnumber<p0
                    if evolve(i,j) == 0
                         patch(app.UIAxes, drawx,drawy,'red','FaceColor','white');
                    else
                        patch(app.UIAxes, drawx,drawy,'red','FaceColor','yellow');
                    end                
                else
                    patch(app.UIAxes, drawx,drawy,'red','FaceColor','cyan');
                    map(i,j) = 0;
                end
            end
        end
    end
   
   transition_P = zeros(size,size,size,size);
   for i = 1 : size
       for j = 1 : size
           if (map(i,j) == 1)
            avail = 0;
            for k = 1 : 6
                if mod(i, 2) == 1
                  newx = i + oddx(k);
                  newy = j + oddy(k);
                end
                if mod(i, 2) == 0
                    newx = i + evenx(k);
                    newy = j + eveny(k);
                end
                if (newx >0 && newy>0 && newx <=size && newy<=size && map(newx, newy) == 1)
                    avail = avail + 1;
                    canx(avail) = newx;
                    cany(avail) = newy;
                end
            end
            for k = 1 : avail
                transition_P(i, j, canx(k), cany(k)) = 1/6;
                
            end
            transition_P(i, j, i, j) = 1 - avail/6;
           end
       end
   end 
   U = 0;
   thereshold = zeros(size, size);
    
   for yi = 1 : size
       for yj = 1 : size
           thereshold(yi, yj) = 0;
           for k = 1 : evolv_number
               thereshold(yi, yj) = thereshold(yi,yj) + transition_P(evolv_x(k), evolv_y(k), yi, yj);
           end
       end
   end
    
    if mod(x,2) == 1
        newx = x + oddx(randomInteger);
        newy = y + oddy(randomInteger);
    end
    if mod(x, 2) == 0
        newx = x + evenx(randomInteger);
        newy = y + eveny(randomInteger);
    end
    if newx > 0 && newy > 0 && newx < size && newy < size && map(newx, newy) == 1
       
       if evolve(x,y)==0
        for k = 1 : 6
            drawx(k) = center(x,y,1) + l1(k);
            drawy(k) = center(x,y,2) + l2(k);
        end
        patch(app.UIAxes,drawx,drawy,'red','FaceColor','white');
        
        else
            for k = 1 : 6
            drawx(k) = center(x,y,1) + l1(k);
            drawy(k) = center(x,y,2) + l2(k);
            end
        patch(app.UIAxes,drawx,drawy,'red','FaceColor','yellow');
        end
       
        x = newx;
        y = newy;
    else
        newx = x;
        newy = y;
    end

    
    thereshold = round(thereshold, 5);
   lb = thereshold(newx, newy);
   [unique_ele, ia,ic] = unique(thereshold);
   l = length(unique_ele);
   ele_num = accumarray(ic,1);
    prob = zeros(l,1);
   for k = 1 : l-1
   if unique_ele(k+1)< lb + 0.0005
       prob(k) = sum(ele_num(k+1:l)) * (unique_ele(k+1) - unique_ele(k));
   end
   end
   prob = prob / sum(prob);
   selected_index = find(rand < cumsum(prob), 1, 'first');
   tr = unique_ele(selected_index+1);
   evolv_number = 0;
   
   for i = 1 : size
       for j = 1 : size
           if thereshold(i,j) > tr - 0.0005
               evolv_number = evolv_number + 1;
               evolv_x(evolv_number) = i;
               evolv_y(evolv_number) = j;
               if (evolve(i,j)==0)
                   for k0 = 1 : 6
                       drawx(k0) = center(i,j,1) + l1(k0);
                       drawy(k0) = center(i,j,2) + l2(k0);
                   end
                   patch(app.UIAxes,drawx,drawy,'red','FaceColor','yellow')
               end
                   evolve(i,j) = 1;
           else
               if (evolve(i,j) == 1) 
               evolve(i,j) = 0;
               if map(i,j)==1
               for k0 = 1 : 6
                       drawx(k0) = center(i,j,1) + l1(k0);
                       drawy(k0) = center(i,j,2) + l2(k0);
               end
               patch(app.UIAxes,drawx,drawy,'red','FaceColor','white')
               else
                   for k0 = 1 : 6
                       drawx(k0) = center(i,j,1) + l1(k0);
                       drawy(k0) = center(i,j,2) + l2(k0);
                   end
               patch(app.UIAxes,drawx,drawy,'red','FaceColor','cyan')
               end
               end
           end
       end
end
   
    for k = 1 : 6
            drawx(k) = center(newx,newy,1) + l1(k);
            drawy(k) = center(newx,newy,2) + l2(k);
    end
    patch(app.UIAxes,drawx,drawy,'red','FaceColor','blue');
    
    pause(0.1);
end
close(writerObj);
            
        end

        % Callback function
        function StopButtonPushed(app, event)
            delete(app)
        end

        % Callback function
        function StartButtonPushed2(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {484, 484};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {166, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.pEditFieldLabel = uilabel(app.LeftPanel);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.LeftPanel, 'numeric');
            app.pEditField.Value = 0.5;
            app.muLabel = uilabel(app.LeftPanel);
            app.muLabel.Interpreter = 'latex';
            app.muLabel.Text = '\mu';
            app.muEditField = uieditfield(app.LeftPanel, 'numeric');
            app.muEditField.Value = 0.01;
            app.sizeEditFieldLabel = uilabel(app.LeftPanel);
            app.sizeEditFieldLabel.Text = 'size';
            app.sizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.sizeEditField.Value = 30;
            app.iterationEditFieldLabel = uilabel(app.LeftPanel);
            app.iterationEditFieldLabel.Text = 'iteration ';
            app.iterationEditField = uieditfield(app.LeftPanel, 'numeric');
            app.iterationEditField.Value = 5;
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = ' ';
            app.ParametersLabel = uilabel(app.LeftPanel);
            app.ParametersLabel.Text = 'Parameters:';
            app.LegendLabel = uilabel(app.LeftPanel);
            app.LegendLabel.Text = 'Legend:';
            app.EvolvingsetLabel = uilabel(app.LeftPanel);
            app.EvolvingsetLabel.Text = 'Evolving set';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = ' ';
            app.ClosedvertexLabel = uilabel(app.LeftPanel);
            app.ClosedvertexLabel.Text = 'Closed vertex';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = ' ';
            app.OpenvertexLabel = uilabel(app.LeftPanel);
            app.OpenvertexLabel.Text = 'Open vertex';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end