classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        iterationEditField       matlab.ui.control.NumericEditField
        iterationEditFieldLabel  matlab.ui.control.Label
        sizeEditField            matlab.ui.control.NumericEditField
        sizeEditFieldLabel       matlab.ui.control.Label
        muEditField              matlab.ui.control.NumericEditField
        muEditFieldLabel         matlab.ui.control.Label
        pEditField               matlab.ui.control.NumericEditField
        pEditFieldLabel          matlab.ui.control.Label
        StartButton              matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
       
axis(app.UIAxes, 'equal'); 
app.UIAxes.XColor = 'none';
app.UIAxes.YColor = 'none';
drawx = zeros(6,1);
drawy = zeros(6,1);
a = 3 ^ (1/2) ;
p = app.pEditField.Value;
l1 = [-1/2, 1/2, 1, 1/2,-1/2, -1];
l2 = [a/2, a/2, 0, -a/2, -a/2, 0];
size = app.sizeEditField.Value;
for i = 1 : size
    for j = 1 : size
        if (mod(i, 2) == 1)
            center(i,j,1) = i * 3/2;
            center(i,j,2) = j * a;
        else
            center(i,j,1) = i * 3/2;
            center(i,j,2) = j * a + 1/2 * a;
        end

        for k = 1 : 6
            drawx(k) = center(i,j,1) + l1(k);
            drawy(k) = center(i,j,2) + l2(k);
        end
        
        rnumber = rand();
        if rnumber<p
            patch(app.UIAxes, drawx,drawy,'red','FaceColor','white');
            map(i,j) = 1;
        else
            patch(app.UIAxes, drawx,drawy,'red','FaceColor','cyan');
            map(i,j) = 0;
        end
    end
end
iter_num = app.iterationEditField.Value;
x = floor(size / 2);
y = floor(size / 2);
for k = 1 : 6
            drawx(k) = center(x,y,1) + l1(k);
            drawy(k) = center(x,y,2) + l2(k);
end
patch(app.UIAxes,drawx,drawy,'red','FaceColor','blue');
refreshrate = app.muEditField.Value;
evenx = [-1,-1,0,0,1,1];
eveny = [0,1,-1,1,0,1];
oddx = [-1,-1,0,0,1,1];
oddy = [0,-1,-1,1,0,-1];
newx = 0; newy = 0;
for t = 1 : iter_num
    app.iterationEditField.Value = t;
    randomInteger = randi([1, 6]);
    for i = 1 : size
        for j = 1 : size
            refreshrand = rand();
            if (i == x && j == y) 
                continue;
            end
            if refreshrand < refreshrate
                for k = 1 : 6
                    drawx(k) = center(i,j,1) + l1(k);
                    drawy(k) = center(i,j,2) + l2(k);
                end
                rnumber = rand();
                if rnumber<p
                    patch(app.UIAxes,drawx,drawy,'red','FaceColor','white');
                    map(i,j) = 1;
                else
                    patch(app.UIAxes,drawx,drawy,'red','FaceColor','cyan');
                    map(i,j) = 0;
                end
            end
        end
    end
   
    if mod(x,2) == 1
        newx = x + oddx(randomInteger);
        newy = y + oddy(randomInteger);
    end
    if mod(x, 2) == 0
        newx = x + evenx(randomInteger);
        newy = y + eveny(randomInteger);
    end
    if newx > 0 && newy > 0 && newx < size && newy < size && map(newx, newy) == 1
        for k = 1 : 6
            drawx(k) = center(x,y,1) + l1(k);
            drawy(k) = center(x,y,2) + l2(k);
        end
        patch(app.UIAxes,drawx,drawy,'red','FaceColor','white');
        x = newx;
        y = newy;
        for k = 1 : 6
            drawx(k) = center(x,y,1) + l1(k);
            drawy(k) = center(x,y,2) + l2(k);
        end
        patch(app.UIAxes,drawx,drawy,'red','FaceColor','blue');
    end
    pause(0.001);
end


        end

        % Callback function
        function StopButtonPushed(app, event)
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {484, 484};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {166, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.pEditFieldLabel = uilabel(app.LeftPanel);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.LeftPanel, 'numeric');
            app.pEditField.Value = 0.5;
            app.muEditFieldLabel = uilabel(app.LeftPanel);
            app.muEditFieldLabel.Text = 'mu';
            app.muEditField = uieditfield(app.LeftPanel, 'numeric');
            app.muEditField.Value = 0.01;
            app.sizeEditFieldLabel = uilabel(app.LeftPanel);
            app.sizeEditFieldLabel.Text = 'size';
            app.sizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.sizeEditField.Value = 20;
            app.iterationEditFieldLabel = uilabel(app.LeftPanel);
            app.iterationEditFieldLabel.Text = 'iteration ';
            app.iterationEditField = uieditfield(app.LeftPanel, 'numeric');
            app.iterationEditField.Value = 50;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end